classdef TSexp_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CSynIndexEditField             matlab.ui.control.EditField
        CSynIndexEditFieldLabel        matlab.ui.control.Label
        FamiliarizationFlagCheckBox    matlab.ui.control.CheckBox
        RunExperimentButton            matlab.ui.control.Button
        MPCPredictionHorizonEditField  matlab.ui.control.NumericEditField
        MPCPredictionHorizonEditFieldLabel  matlab.ui.control.Label
        TrialFlagCheckBox              matlab.ui.control.CheckBox
        CalibrationFlagCheckBox        matlab.ui.control.CheckBox
        HMLModelParamsEditField        matlab.ui.control.EditField
        HMLModelParamsEditFieldLabel   matlab.ui.control.Label
        TSPolicyDropDown               matlab.ui.control.DropDown
        TSPolicyDropDownLabel          matlab.ui.control.Label
        SubjectIDEditField             matlab.ui.control.EditField
        SubjectIDEditFieldLabel        matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunExperimentButton
        function RunExperimentButtonPushed(app, event)
            assignin('base', 'subjectID', app.SubjectIDEditField.Value)
            assignin('base', 'HMLparams', app.HMLModelParamsEditField.Value)
            assignin('base', 'policy', app.TSPolicyDropDown.Value)
            assignin('base', 'MPC_predHorizon', app.MPCPredictionHorizonEditField.Value)
            assignin('base', 'calibrationFlag', app.CalibrationFlagCheckBox.Value)
            assignin('base', 'familiarizationFlag', app.FamiliarizationFlagCheckBox.Value)
            assignin('base', 'runTrialFlag', app.TrialFlagCheckBox.Value)
            assignin('base', 'C_syn_idx', str2num(app.CSynIndexEditField.Value))
            app.delete
        end

        % Callback function
        function ImportRLagentButtonPushed(app, event)
            [file, location] = uigetfile;
            fname = [location file];
            assignin('base', 'rlSaveFile', load(fname))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.Value = 'test';
            app.TSPolicyDropDownLabel = uilabel(app.UIFigure);
            app.TSPolicyDropDownLabel.Text = 'TS Policy';
            app.TSPolicyDropDown = uidropdown(app.UIFigure);
            app.TSPolicyDropDown.Items = {'rand', 'mpc', 'manual'};
            app.TSPolicyDropDown.Value = 'mpc';
            app.HMLModelParamsEditFieldLabel = uilabel(app.UIFigure);
            app.HMLModelParamsEditFieldLabel.Text = 'HML Model Params';
            app.HMLModelParamsEditField = uieditfield(app.UIFigure, 'text');
            app.HMLModelParamsEditField.Value = '[0.000189497658958333 265.086429250000 0.0797413009833333 451.697288166667 94.1098903916667 11.1223049841667]';
            app.CalibrationFlagCheckBox = uicheckbox(app.UIFigure);
            app.CalibrationFlagCheckBox.Text = 'Calibration Flag';
            app.CalibrationFlagCheckBox.Value = true;
            app.TrialFlagCheckBox = uicheckbox(app.UIFigure);
            app.TrialFlagCheckBox.Text = 'Trial Flag';
            app.TrialFlagCheckBox.Value = true;
            app.MPCPredictionHorizonEditFieldLabel = uilabel(app.UIFigure);
            app.MPCPredictionHorizonEditFieldLabel.Text = 'MPC Prediction Horizon';
            app.MPCPredictionHorizonEditField = uieditfield(app.UIFigure, 'numeric');
            app.MPCPredictionHorizonEditField.Value = 4;
            app.RunExperimentButton = uibutton(app.UIFigure, 'push');
            app.RunExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButtonPushed, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            app.FamiliarizationFlagCheckBox = uicheckbox(app.UIFigure);
            app.FamiliarizationFlagCheckBox.Text = 'Familiarization Flag';
            app.FamiliarizationFlagCheckBox.Value = true;
            app.CSynIndexEditFieldLabel = uilabel(app.UIFigure);
            app.CSynIndexEditFieldLabel.Text = 'C Syn Index';
            app.CSynIndexEditField = uieditfield(app.UIFigure, 'text');
            app.CSynIndexEditField.Value = '[2,3]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSexp_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end